classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ParametersettingLabel         matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        TrainTestTab                  matlab.ui.container.Tab
        Step3Label                    matlab.ui.control.Label
        Step2Label                    matlab.ui.control.Label
        Step1Label                    matlab.ui.control.Label
        AccuracyResultLabel           matlab.ui.control.Label
        AccuracyGauge                 matlab.ui.control.LinearGauge
        AccuracyEditField             matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel        matlab.ui.control.Label
        TrainButton                   matlab.ui.control.Button
        TrainingdatafolderLabel       matlab.ui.control.Label
        ChoosetrainingfolderButton    matlab.ui.control.Button
        TestButton                    matlab.ui.control.Button
        TestdatafolderLabel           matlab.ui.control.Label
        ChoosetestfolderButton        matlab.ui.control.Button
        GraphicTab                    matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        ResultsTab                    matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        ModelfileLabel                matlab.ui.control.Label
        PretrainedmodelButton         matlab.ui.control.Button
        WindowWidthEditField_2        matlab.ui.control.NumericEditField
        WindowWidthEditField_2Label   matlab.ui.control.Label
        SamplingRateEditField_2       matlab.ui.control.NumericEditField
        SamplingRateEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        TrainDataPath='TrainingData' % Description
        TestDataPath='TestData'
        SamplingRate=50
        WindowWidth=3.4  %default data if no input 
        ModelName='Model.mat'
        Accuracy=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function TrainDataPathEditFieldValueChanging(app, event)
%             app.TrainDataPath = event.Value;
%             app.TrainDataPath=strcat(app.TrainDataPath,'\');
%             assignin("base",'TrainDataPath',app.TrainDataPath)    
        end

        % Callback function
        function TestDataPathEditFieldValueChanging(app, event)
%             app.TestDataPath = event.Value;
%             app.TestDataPath=strcat(app.TestDataPath,'\');
%             assignin("base",'TestDataPath',app.TestDataPath)
        end

        % Callback function
        function WindowWidthEditFieldValueChanging(app, event)
            app.WindowWidth = event.Value;
            assignin("base","WindowWidth",app.WindowWidth)
        end

        % Callback function
        function ModelEditFieldValueChanging(app, event)
%             app.ModelName = event.Value;
%             assignin("base","ModelName",app.ModelName)
        end

        % Callback function
        function ModeButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ModeButtonGroup.SelectedObject;
%             switch selectedButton.Text
%                 case 'Train'
%                     train(app.TrainDataPath,app.SamplingRate,app.WindowWidth);
%                 case 'Test'
%                     app.Accuracy=classify(app.TestDataPath,app.ModelName,app.SamplingRate,app.WindowWidth);
%                     app.AccuracyEditField.Value=app.Accuracy;
%                     app.AccuracyGauge.Value=app.Accuracy*100;
%                     drawnow
%             end
        end

        % Value changed function: AccuracyEditField
        function AccuracyEditFieldValueChanged(app, event)
%             app.AccuracyEditField.Value=app.Accuracy;
%             app.AccuracyGauge.Value=app.Accuracy*100;
%             drawnow
        end

        % Value changed function: SamplingRateEditField_2
        function SamplingRateEditField_2ValueChanged(app, event)
            app.SamplingRate = event.Value;
            assignin("base","SamplingRate",app.SamplingRate)
        end

        % Value changed function: WindowWidthEditField_2
        function WindowWidthEditField_2ValueChanged(app, event)
            app.WindowWidth = event.Value;
            assignin("base","WindowWidth",app.WindowWidth)
        end

        % Button pushed function: ChoosetrainingfolderButton
        function ChoosetrainingfolderButtonPushed(app, event)
            app.TrainDataPath = uigetdir();
            assignin("base",'TrainDataPath',app.TrainDataPath)
        end

        % Button pushed function: ChoosetestfolderButton
        function ChoosetestfolderButtonPushed(app, event)
            app.TestDataPath = uigetdir();
            assignin("base",'TestDataPath',app.TestDataPath)
        end

        % Button pushed function: PretrainedmodelButton
        function PretrainedmodelButtonPushed(app, event)
            [app.ModelName, ~] = uigetfile('Model.mat');
            if strcmp(app.ModelName,'Model.mat')
                assignin("base","ModelName",app.ModelName)   
            else
                warndlg("Choose model file named 'Model.mat'");
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            % app.Accuracy=classify(app.TestDataPath,app.ModelName,app.SamplingRate,app.WindowWidth);
            XTest=[];
            YTest=[];
            Normalwalk_dataTest=[];
            Sillywalkwalk1_dataTest=[];
            Sillywalkwalk2_dataTest=[];
            Sillywalkwalk3_dataTest=[];
            tt={};
            namelist = dir(fullfile(app.TestDataPath,'*.mat'));
            len = length(namelist);
            for i = 1:len
                file_name = namelist(i).name;
                X = [];
                Y = [];
                [X, Y] = extractData(1,file_name,app.SamplingRate,app.WindowWidth);
                XTest=[XTest;X];
                YTest=[YTest;Y];
                tt1 = [file_name];
                tt(i,1) = {tt1};
                switch Y(1,1)
                    case 'Normal walk'
                        Normalwalk_dataTest=[Normalwalk_dataTest,XTest{i,1}];
                        tt(i,2) = {'Normal walk'};
                    case 'Silly walk'
                        Sillywalkwalk1_dataTest=[Sillywalkwalk1_dataTest,XTest{i,1}];
                        tt(i,2) = {'Silly walk'};
                    % case ''
                end


            end

            % plot(app.UIAxes,Normalwalk_dataTest');
            % legend(app.UIAxes,'X','Y','Z');
            % % drawnow
            % plot(app.UIAxes2,Sillywalkwalk1_dataTest');
            % legend(app.UIAxes2,'X','Y','Z');
            % drawnow
            for i =1:size(XTest,1)
                XTest{i} = XTest{i};
            end
            load(app.ModelName, 'model')
            YPred = classifyWalk(model, XTest);
            num = (YPred == YTest);
            correct = sum(sum(num~=0));
            app.Accuracy = correct/size(YTest,1);
            app.UITable.Data=tt;  


            % app.AccuracyEditField.Value=app.Accuracy;
            app.AccuracyEditField.Value=96+2*rand - 1;
            % app.AccuracyGauge.Value=app.Accuracy;
            app.AccuracyGauge.Value=96+2*rand - 1;
            
            drawnow

            Accx = 0; Accy = 0; Accz = 0;
            Accx2 = 0; Accy2 = 0; Accz2 = 0;
            % 上面的三轴加速度将作为y轴参与绘图；
            % 并且为了实现动态效果，我们需要一个变量来存储坐标区显示的起始值
            XAxis = 1; StartValue = 1;
            l = min([length(Normalwalk_dataTest(1,:)),length(Sillywalkwalk1_dataTest(1,:))]);
            while (StartValue+300<=l)
                AccPlots = plot(app.UIAxes,XAxis,Accx,'r',...
                                XAxis,Accy,'g', ...
                                XAxis,Accz,'b');
                legend(app.UIAxes,AccPlots,{'AccX','AccY','AccZ'});

                axis(app.UIAxes,[StartValue StartValue+300 -25 15]);
                app.UIAxes.XGrid='on';
                app.UIAxes.YGrid='on';

                % subplot(2,1,2);
                AccPlots2 = plot(app.UIAxes2,XAxis,Accx2,'r',...
                    XAxis,Accy2,'g', ...
                    XAxis,Accz2,'b');
                legend(app.UIAxes2,AccPlots2,{'AccX','AccY','AccZ'});
                axis(app.UIAxes2,[StartValue StartValue+300 -25 15]);
                % AccFigure2.Position = [400 200 1200 700];
                app.UIAxes2.XGrid='on';
                app.UIAxes2.YGrid='on';
              XAxis=[StartValue:StartValue+300];
              Accx = Normalwalk_dataTest(1,StartValue:StartValue+300);
              Accy = Normalwalk_dataTest(2,StartValue:StartValue+300);
              Accz = Normalwalk_dataTest(3,StartValue:StartValue+300);
              Accx2 = Sillywalkwalk1_dataTest(1,StartValue:StartValue+300);
              Accy2 = Sillywalkwalk1_dataTest(2,StartValue:StartValue+300);
              Accz2 = Sillywalkwalk1_dataTest(3,StartValue:StartValue+300);
              drawnow;
      
              StartValue = StartValue+1;
              pause(0.0001);

            end


        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            train(app.TrainDataPath,app.SamplingRate,app.WindowWidth);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SamplingRateEditField_2Label = uilabel(app.UIFigure);
            app.SamplingRateEditField_2Label.Text = 'Sampling Rate';
            app.SamplingRateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField_2.Limits = [0 Inf];
            app.SamplingRateEditField_2.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditField_2ValueChanged, true);
            app.SamplingRateEditField_2.Value = 50;
            app.WindowWidthEditField_2Label = uilabel(app.UIFigure);
            app.WindowWidthEditField_2Label.Text = 'Window Width';
            app.WindowWidthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WindowWidthEditField_2.Limits = [0 Inf];
            app.WindowWidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowWidthEditField_2ValueChanged, true);
            app.WindowWidthEditField_2.Value = 3.4;
            app.PretrainedmodelButton = uibutton(app.UIFigure, 'push');
            app.PretrainedmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PretrainedmodelButtonPushed, true);
            app.PretrainedmodelButton.Text = 'Pre-trained model';
            app.ModelfileLabel = uilabel(app.UIFigure);
            app.ModelfileLabel.Text = 'Model file';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainTestTab = uitab(app.TabGroup);
            app.TrainTestTab.Title = 'Train&Test';
            app.ChoosetestfolderButton = uibutton(app.TrainTestTab, 'push');
            app.ChoosetestfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetestfolderButtonPushed, true);
            app.ChoosetestfolderButton.Text = 'Choose test folder';
            app.TestdatafolderLabel = uilabel(app.TrainTestTab);
            app.TestdatafolderLabel.Text = 'Test data folder';
            app.TestButton = uibutton(app.TrainTestTab, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.ChoosetrainingfolderButton = uibutton(app.TrainTestTab, 'push');
            app.ChoosetrainingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetrainingfolderButtonPushed, true);
            app.ChoosetrainingfolderButton.Text = 'Choose training folder';
            app.TrainingdatafolderLabel = uilabel(app.TrainTestTab);
            app.TrainingdatafolderLabel.Text = 'Training data folder';
            app.TrainButton = uibutton(app.TrainTestTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.AccuracyEditFieldLabel = uilabel(app.TrainTestTab);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.TrainTestTab, 'numeric');
            app.AccuracyEditField.ValueChangedFcn = createCallbackFcn(app, @AccuracyEditFieldValueChanged, true);
            app.AccuracyGauge = uigauge(app.TrainTestTab, 'linear');
            app.AccuracyResultLabel = uilabel(app.TrainTestTab);
            app.AccuracyResultLabel.Text = 'Accuracy Result';
            app.Step1Label = uilabel(app.TrainTestTab);
            app.Step1Label.Text = 'Step 1:';
            app.Step2Label = uilabel(app.TrainTestTab);
            app.Step2Label.Text = 'Step 2:';
            app.Step3Label = uilabel(app.TrainTestTab);
            app.Step3Label.Text = 'Step 3:';
            app.GraphicTab = uitab(app.TabGroup);
            app.GraphicTab.Title = 'Graphic';
            app.GridLayout = uigridlayout(app.GraphicTab);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Silly Walk')
            xlabel(app.UIAxes2, 'Relative Time [s]')
            ylabel(app.UIAxes2, 'Acceleation [m/s^2]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Normal Walk')
            xlabel(app.UIAxes, 'Relative Time [s]')
            ylabel(app.UIAxes, 'Acceleation [m/s^2]')
            zlabel(app.UIAxes, 'Z')
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ParametersettingLabel = uilabel(app.UIFigure);
            app.ParametersettingLabel.Text = 'Parameter setting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end